classdef Fase1MYKI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Fase1Tab                        matlab.ui.container.Tab
        GrigliaMAIN                     matlab.ui.container.GridLayout
        Questionario                    matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        SalvaconTactoreGloveButton      matlab.ui.control.Button
        SalvacondatiTactorButton        matlab.ui.control.Button
        Domanda2cosnonmodificabileLabel  matlab.ui.control.Label
        ListBox_4                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ListBox_2                       matlab.ui.control.ListBox
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        ListBox                         matlab.ui.control.ListBox
        TextArea_2                      matlab.ui.control.TextArea
        CyberGloveCalibrationDataPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        CybergloveStreamONButton        matlab.ui.control.StateButton
        PosizListBox                    matlab.ui.control.ListBox
        PosizListBoxLabel               matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        SalvaxlsxPosizioneButton        matlab.ui.control.Button
        InterrogaCyberGloveButton       matlab.ui.control.Button
        TactorsPanel                    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TimemsLabel                     matlab.ui.control.Label
        GainLabel                       matlab.ui.control.Label
        FreqHzLabel                     matlab.ui.control.Label
        FreqHzKnob                      matlab.ui.control.Knob
        TactorsONPanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Tactor3CheckBox                 matlab.ui.control.CheckBox
        Tactor2CheckBox                 matlab.ui.control.CheckBox
        Tactor1CheckBox                 matlab.ui.control.CheckBox
        VIBRAButton                     matlab.ui.control.Button
        DuratamsKnob                    matlab.ui.control.Knob
        EditFieldDur                    matlab.ui.control.NumericEditField
        EditFieldGain                   matlab.ui.control.NumericEditField
        GainKnob                        matlab.ui.control.Knob
        EditFieldFreq                   matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        DisconnettiCyberGloveButton     matlab.ui.control.Button
        ConnettiCybergloveButton        matlab.ui.control.Button
        InizializzaTactorsButton        matlab.ui.control.Button
        AggiornaPorteCOMButton          matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        PortaListBox                    matlab.ui.control.ListBox
        Fase2Tab                        matlab.ui.container.Tab
        Fase3Tab                        matlab.ui.container.Tab
        Fase4Tab                        matlab.ui.container.Tab
    end

    
    properties (Access = private)
        oggi;
        percorso;
        glove;
        tab;
        ti;
        porta_com;
        DataTags;
    end
    
    methods (Access = private)

        function uccidiGlove(app)
            try
                fclose(app.glove.serial_p);
                delete(app.glove.serial_p);
                clear app.glove
            catch
                disp("no gloves");
            end

            try
                clear app.ti
            catch
                disp("no tactors");
            end
        end

        function comwin_on(app) %#ok<MANU> 
            if exist("comwin.txt","file")
                delete("comwin.txt");
            end
            diary comwin.txt
            diary on
        end

        function comwin_off(app)
            diary off
            app.TextArea.Value = regexp(fileread('comwin.txt'), '\r?\n','split');
        end

        function posta(app,str)
            comwin_on(app)
            disp(str);
            comwin_off(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isequal(pwd, 'C:\Users\Mattia Gentile\OneDrive\Documenti\SSUP\ScriptsMYKI\MYKI-psychophysics')          % inserire qui il proprio percorso
                posta(app,"Avviare dalla cartella giusta! 5... 4... 3...")                                              % magari un giorno lo fixerò
                pause(5)
                delete(app)
            end
            
            app.oggi = string(datetime('now','Format','dd-MM-yy'));
            app.percorso = strcat('Fase1_',app.oggi);
            addpath MATLAB64_TDK-1.0.5.0\

            if ~exist(app.percorso, 'dir')
                mkdir(app.percorso);
                addpath(app.percorso);
            end

            serials = serialportlist;
            app.PortaListBox.Items = serials;
            app.porta_com = app.PortaListBox.Value;

            if ~exist("DataTags","var")
                if exist("DataTags.mat", "file") ~= 0
                    load DataTags.mat DataTags
                end
            end

            app.DataTags = DataTags; %#ok<ADPROP>         

            app.EditFieldDur.Value = 2000;
            app.EditFieldFreq.Value = 100;
            app.EditFieldGain.Value = 200;

            app.DuratamsKnob.Value = app.EditFieldDur.Value;
            app.FreqHzKnob.Value = app.EditFieldFreq.Value;
            app.GainKnob.Value = app.EditFieldGain.Value;

            app.TextArea.Value = ["Fase 1 Istruzioni:"; "Aggiorna Porte COM prima di connettere Tactors o Cyberglove "; ...
                "Ricordarsi di Disconnettere CyberGlove prima di riprovare con Connetti"; ...
                "Dopo aver connesso Tactors si può Vibrare"; ...
                "Dopo aver connesso CyberGlove si può Interrogare o Streamare"];

        end

        % Button pushed function: InizializzaTactorsButton
        function InizializzaTactorsButtonPushed(app, event)
            comwin_on(app);
            
            app.ti = TactorInterfaceML;
            
            try
                numFound = app.ti.Discover;
                if numFound > 0
                    app.ti.Connect(app.PortaListBox.Value, 1);
                end
            catch
                disp("Nessun Tactor Inizializzato");
            end

            comwin_off(app);
        end

        % Button pushed function: AggiornaPorteCOMButton
        function AggiornaPorteCOMButtonPushed(app, event)
            serials = serialportlist;
            app.PortaListBox.Items = serials;
        end

        % Button pushed function: InterrogaCyberGloveButton
        function InterrogaCyberGloveButtonPushed(app, event)
            write(app.glove.serial_p, 'G', 'uint8');
            data = read(app.glove.serial_p, 20, 'uint8');

            if data(20) == 0
                if isempty(app.tab)
                    app.tab = data;
                else
                    app.tab = [app.tab; data];
                end
            end
            
            temp_tab = array2table(app.tab);
            app.UITable.Data = temp_tab;
        end

        % Button pushed function: SalvaxlsxPosizioneButton
        function SalvaxlsxPosizioneButtonPushed(app, event)
            if ~isa(app.tab, "table")
                app.tab = array2table(app.tab);
                app.tab.Properties.VariableNames(1:20) = app.DataTags;
            end
            
            writetable(app.tab,strcat(app.percorso,"\","cal_", app.PosizListBox.Value,"_",app.oggi,".xlsx"));
        end

        % Button pushed function: VIBRAButton
        function VIBRAButtonPushed(app, event)
            try
            freq = round(10 * app.FreqHzKnob.Value);                        % la funzione ChangeFreq vuole in ingresso decimi di Hz
            gain = round(app.GainKnob.Value);
            vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];
            pulse_delta = round(app.DuratamsKnob.Value);
            delay = pulse_delta / 10 - 1;                                   % bisogna fornire i delay in centesimi di secondo

            if (app.ti.deviceConnected == true && vib_index(1) == 1)
                app.ti.ChangeFreq(devIndex, 1, freq, 0);
                app.ti.ChangeGain(devIndex, 1, gain, 0);
                app.ti.Pulse(devIndex,1, pulse_delta, 0);
                app.ti.Pulse(devIndex, 1, pulse_delta, delay-2);
            end
            if (app.ti.deviceConnected == true && vib_index(2) == 1)
                app.ti.ChangeFreq(devIndex, 2, freq, 0);
                app.ti.ChangeGain(devIndex, 2, gain, 0);
                app.ti.Pulse(devIndex, 2, pulse_delta, 1);
                app.ti.Pulse(devIndex, 2, pulse_delta, delay-1);
            end
            if (app.ti.deviceConnected == true && vib_index(3) == 1)
                app.ti.ChangeFreq(devIndex, 3, freq, 0);
                app.ti.ChangeGain(devIndex, 3, gain, 0);
                app.ti.Pulse(devIndex, 3, pulse_delta, 2);
                app.ti.Pulse(devIndex, 3, pulse_delta, delay);
            end
            catch
                posta(app,"Non ci sono i Tactors collegati!!!");
            end
        end

        % Button pushed function: ConnettiCybergloveButton
        function ConnettiCybergloveButtonPushed(app, event)
            comwin_on(app);

            try
                app.porta_com = app.PortaListBox.Value;
                app.glove = Cyberglove(app.porta_com);
            catch
                disp("CONNESSIONE CYBERGLOVE FALLITA!!!")
            end

            comwin_off(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uccidiGlove(app)

            if isfile("comwin.txt")
                delete("comwin.txt");
            end

            delete(app)            
        end

        % Button pushed function: DisconnettiCyberGloveButton
        function DisconnettiCyberGloveButtonPushed(app, event)
            comwin_on(app);
            uccidiGlove(app);
            comwin_off(app);
        end

        % Value changed function: EditFieldFreq
        function EditFieldFreqValueChanged(app, event)
            value = app.EditFieldFreq.Value;
            app.FreqHzKnob.Value = value;
        end

        % Value changed function: EditFieldGain
        function EditFieldGainValueChanged(app, event)
            value = app.EditFieldGain.Value;
            app.GainKnob.Value = value;
        end

        % Value changed function: EditFieldDur
        function EditFieldDurValueChanged(app, event)
            value = app.EditFieldDur.Value;
            app.DuratamsKnob.Value = value;
        end

        % Value changed function: FreqHzKnob
        function FreqHzKnobValueChanged(app, event)
            value = app.FreqHzKnob.Value;
            app.EditFieldFreq.Value = value;
        end

        % Value changed function: GainKnob
        function GainKnobValueChanged(app, event)
            value = app.GainKnob.Value;
            app.EditFieldGain.Value = value;
        end

        % Value changed function: DuratamsKnob
        function DuratamsKnobValueChanged(app, event)
            value = app.DuratamsKnob.Value;
            app.EditFieldDur.Value = value;
        end

        % Value changed function: CybergloveStreamONButton
        function CybergloveStreamONButtonValueChanged(app, event)
            % stream_on = app.CybergloveStreamONButton.Value;
            % https://it.mathworks.com/help/matlab/creating_guis/wind-speed-gui-in-app-designer.html
            % Qui devo usare la funzione ReadData da cybercoso.m

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'F1MYKI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Fase1Tab = uitab(app.TabGroup);
            app.Fase1Tab.Title = 'Fase 1';
            app.GrigliaMAIN = uigridlayout(app.Fase1Tab);
            app.Panel = uipanel(app.GrigliaMAIN);
            app.Panel.Title = 'Panel';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.PortaListBox = uilistbox(app.GridLayout3);
            app.PortaListBox.Items = {'Lista', 'Porte COM'};
            app.PortaListBox.Value = {};
            app.TextArea = uitextarea(app.GridLayout3);
            app.AggiornaPorteCOMButton = uibutton(app.GridLayout3, 'push');
            app.AggiornaPorteCOMButton.ButtonPushedFcn = createCallbackFcn(app, @AggiornaPorteCOMButtonPushed, true);
            app.AggiornaPorteCOMButton.Text = {'Aggiorna'; 'Porte COM'};
            app.InizializzaTactorsButton = uibutton(app.GridLayout3, 'push');
            app.InizializzaTactorsButton.ButtonPushedFcn = createCallbackFcn(app, @InizializzaTactorsButtonPushed, true);
            app.InizializzaTactorsButton.Text = {'Inizializza'; 'Tactors'};
            app.ConnettiCybergloveButton = uibutton(app.GridLayout3, 'push');
            app.ConnettiCybergloveButton.ButtonPushedFcn = createCallbackFcn(app, @ConnettiCybergloveButtonPushed, true);
            app.ConnettiCybergloveButton.Text = {'Connetti'; 'Cyberglove'};
            app.DisconnettiCyberGloveButton = uibutton(app.GridLayout3, 'push');
            app.DisconnettiCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnettiCyberGloveButtonPushed, true);
            app.DisconnettiCyberGloveButton.Text = {'Disconnetti'; 'CyberGlove'};
            app.TactorsPanel = uipanel(app.GrigliaMAIN);
            app.TactorsPanel.Title = 'Tactors';
            app.GridLayout2 = uigridlayout(app.TactorsPanel);
            app.EditFieldFreq = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldFreq.Limits = [60 160];
            app.EditFieldFreq.ValueDisplayFormat = '%.0f';
            app.EditFieldFreq.ValueChangedFcn = createCallbackFcn(app, @EditFieldFreqValueChanged, true);
            app.EditFieldFreq.Value = 60;
            app.GainKnob = uiknob(app.GridLayout2, 'continuous');
            app.GainKnob.Limits = [50 255];
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainKnobValueChanged, true);
            app.GainKnob.Value = 50;
            app.EditFieldGain = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldGain.Limits = [50 255];
            app.EditFieldGain.ValueDisplayFormat = '%.0f';
            app.EditFieldGain.ValueChangedFcn = createCallbackFcn(app, @EditFieldGainValueChanged, true);
            app.EditFieldGain.Value = 50;
            app.EditFieldDur = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldDur.Limits = [100 5000];
            app.EditFieldDur.ValueDisplayFormat = '%.0f';
            app.EditFieldDur.ValueChangedFcn = createCallbackFcn(app, @EditFieldDurValueChanged, true);
            app.EditFieldDur.Value = 100;
            app.DuratamsKnob = uiknob(app.GridLayout2, 'continuous');
            app.DuratamsKnob.Limits = [100 5000];
            app.DuratamsKnob.ValueChangedFcn = createCallbackFcn(app, @DuratamsKnobValueChanged, true);
            app.DuratamsKnob.Value = 100;
            app.VIBRAButton = uibutton(app.GridLayout2, 'push');
            app.VIBRAButton.ButtonPushedFcn = createCallbackFcn(app, @VIBRAButtonPushed, true);
            app.VIBRAButton.Text = 'VIBRA';
            app.TactorsONPanel = uipanel(app.GridLayout2);
            app.TactorsONPanel.Title = 'Tactors ON';
            app.GridLayout6 = uigridlayout(app.TactorsONPanel);
            app.Tactor1CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor1CheckBox.Text = 'Tactor 1';
            app.Tactor2CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor2CheckBox.Text = 'Tactor 2';
            app.Tactor3CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor3CheckBox.Text = 'Tactor 3';
            app.FreqHzKnob = uiknob(app.GridLayout2, 'continuous');
            app.FreqHzKnob.Limits = [60 160];
            app.FreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqHzKnobValueChanged, true);
            app.FreqHzKnob.Value = 60;
            app.FreqHzLabel = uilabel(app.GridLayout2);
            app.FreqHzLabel.Text = 'Freq (Hz)';
            app.GainLabel = uilabel(app.GridLayout2);
            app.GainLabel.Text = 'Gain';
            app.TimemsLabel = uilabel(app.GridLayout2);
            app.TimemsLabel.Text = 'Time (ms)';
            app.CyberGloveCalibrationDataPanel = uipanel(app.GrigliaMAIN);
            app.CyberGloveCalibrationDataPanel.Title = 'CyberGlove Calibration Data';
            app.GridLayout4 = uigridlayout(app.CyberGloveCalibrationDataPanel);
            app.InterrogaCyberGloveButton = uibutton(app.GridLayout4, 'push');
            app.InterrogaCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @InterrogaCyberGloveButtonPushed, true);
            app.InterrogaCyberGloveButton.Text = {'Interroga'; 'CyberGlove'};
            app.SalvaxlsxPosizioneButton = uibutton(app.GridLayout4, 'push');
            app.SalvaxlsxPosizioneButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaxlsxPosizioneButtonPushed, true);
            app.SalvaxlsxPosizioneButton.Text = {'Salva .xlsx'; 'Posizione'};
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.PosizListBoxLabel = uilabel(app.GridLayout4);
            app.PosizListBoxLabel.Text = 'Posiz.';
            app.PosizListBox = uilistbox(app.GridLayout4);
            app.PosizListBox.Items = {'Flex', 'Ext'};
            app.PosizListBox.Value = 'Flex';
            app.CybergloveStreamONButton = uibutton(app.GridLayout4, 'state');
            app.CybergloveStreamONButton.ValueChangedFcn = createCallbackFcn(app, @CybergloveStreamONButtonValueChanged, true);
            app.CybergloveStreamONButton.Text = 'Cyberglove Stream ON';
            app.Questionario = uipanel(app.GrigliaMAIN);
            app.Questionario.Title = 'Questionario';
            app.GridLayout7 = uigridlayout(app.Questionario);
            app.TextArea_2 = uitextarea(app.GridLayout7);
            app.TextArea_2.Value = {'Domanda 1'};
            app.ListBox = uilistbox(app.GridLayout7);
            app.ListBox.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ListBox.Value = '5';
            app.TextArea_4 = uitextarea(app.GridLayout7);
            app.TextArea_4.Value = {'Domanda 4, risposte a destra numeriche o del tipo "assente", "poco", "discreto", "forte"?'};
            app.TextArea_5 = uitextarea(app.GridLayout7);
            app.TextArea_5.Value = {'Domanda 3, ha senso creare un pool di domande più grande e estrarle a random ad ogni prova? come Marasco?'};
            app.ListBox_2 = uilistbox(app.GridLayout7);
            app.ListBox_2.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ListBox_2.Value = '5';
            app.ListBox_3 = uilistbox(app.GridLayout7);
            app.ListBox_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ListBox_3.Value = '5';
            app.ListBox_4 = uilistbox(app.GridLayout7);
            app.ListBox_4.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ListBox_4.Value = '5';
            app.Domanda2cosnonmodificabileLabel = uilabel(app.GridLayout7);
            app.Domanda2cosnonmodificabileLabel.Text = 'Domanda 2, così non è modificabile?';
            app.SalvacondatiTactorButton = uibutton(app.GridLayout7, 'push');
            app.SalvacondatiTactorButton.Text = {'Salva con'; 'dati Tactor'};
            app.SalvaconTactoreGloveButton = uibutton(app.GridLayout7, 'push');
            app.SalvaconTactoreGloveButton.Text = {'Salva con'; 'Tactor e Glove'};
            app.Fase2Tab = uitab(app.TabGroup);
            app.Fase2Tab.Title = 'Fase 2';
            app.Fase3Tab = uitab(app.TabGroup);
            app.Fase3Tab.Title = 'Fase 3';
            app.Fase4Tab = uitab(app.TabGroup);
            app.Fase4Tab.Title = 'Fase 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fase1MYKI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end