classdef MYKI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Phase1TrainingandCalibrationTab  matlab.ui.container.Tab
        GrigliaMAIN                   matlab.ui.container.GridLayout
        InfoBoxInitializationPanel    matlab.ui.container.Panel
        GridLayout16                  matlab.ui.container.GridLayout
        TrialEditField                matlab.ui.control.NumericEditField
        TrialEditFieldLabel           matlab.ui.control.Label
        CreateTrialDirectoryPhase1    matlab.ui.control.Button
        ShowDirectoriesButtonPhase1   matlab.ui.control.Button
        CloseTactorInterfaceButton    matlab.ui.control.Button
        InitializeTactorInterfaceButton  matlab.ui.control.Button
        COMPortsUpdatePanel           matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        PortaListBox                  matlab.ui.control.ListBox
        InfoBox1                      matlab.ui.control.TextArea
        TactorsPanel                  matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        TimemsLabel                   matlab.ui.control.Label
        GainLabel                     matlab.ui.control.Label
        FreqHzLabel                   matlab.ui.control.Label
        FreqHzKnob                    matlab.ui.control.Knob
        TactorsONPanel                matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        Tactor3CheckBox               matlab.ui.control.CheckBox
        Tactor2CheckBox               matlab.ui.control.CheckBox
        Tactor1CheckBox               matlab.ui.control.CheckBox
        FIREButton                    matlab.ui.control.Button
        DuratamsKnob                  matlab.ui.control.Knob
        EditFieldDur                  matlab.ui.control.NumericEditField
        EditFieldGain                 matlab.ui.control.NumericEditField
        GainKnob                      matlab.ui.control.Knob
        EditFieldFreq                 matlab.ui.control.NumericEditField
        ProprioceptiveIllusionSurveyPanel  matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        DropDown_6                    matlab.ui.control.DropDown
        DropDown_5                    matlab.ui.control.DropDown
        DropDown_4                    matlab.ui.control.DropDown
        DropDown_3                    matlab.ui.control.DropDown
        EditField                     matlab.ui.control.EditField
        DropDown_2                    matlab.ui.control.DropDown
        DropDown                      matlab.ui.control.DropDown
        DefinethespeedofthissensedmovementLabel  matlab.ui.control.Label
        DefinethedistancethismovementspannedontoLabel  matlab.ui.control.Label
        DefinethestrengthvividnessofthemovementsensationLabel  matlab.ui.control.Label
        InwhichdirectionwasthatjoingmovingLabel  matlab.ui.control.Label
        WhatjointdidyoufeelmoveLabel  matlab.ui.control.Label
        DidyoufeelasensationofmovementLabel  matlab.ui.control.Label
        DidyoufeelatactilevibrationLabel  matlab.ui.control.Label
        RepeatStimulationButton       matlab.ui.control.Button
        NextStimulationButton         matlab.ui.control.Button
        SaveSurveyAnswersButton       matlab.ui.control.Button
        StartSurveyButton             matlab.ui.control.Button
        Phase2GloveTrackingTab        matlab.ui.container.Tab
        Phase3AdaptiveThresholdsTab   matlab.ui.container.Tab
        GridLayout11                  matlab.ui.container.GridLayout
        SaveDataPanel                 matlab.ui.container.Panel
        GridLayout15                  matlab.ui.container.GridLayout
        SavebothDataandImageButton    matlab.ui.control.Button
        SaveImageButton               matlab.ui.control.Button
        SaveDataButton                matlab.ui.control.Button
        OutputGraphsPanel             matlab.ui.container.Panel
        GridLayout14                  matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        SequenceInitializationandExecutionPanel  matlab.ui.container.Panel
        GridLayout12                  matlab.ui.container.GridLayout
        MoreIntenseButton             matlab.ui.control.Button
        ShowDirectoriesButton         matlab.ui.control.Button
        SameIntensityButton           matlab.ui.control.Button
        LessIntenseButton             matlab.ui.control.Button
        StartSequenceButton           matlab.ui.control.Button
        CreateTrialDirectoryButton    matlab.ui.control.Button
        InfoBox3                      matlab.ui.control.TextArea
        TrialVariableDropDown         matlab.ui.control.DropDown
        TrialVariableDropDownLabel    matlab.ui.control.Label
        ThisTrialNEditField           matlab.ui.control.NumericEditField
        ThisTrialNEditFieldLabel      matlab.ui.control.Label
    end

    properties (Access = private)
        today;
        path_1;
        path_2;
        path_3;
        
        % Phase 1 Properties
        this_trial_path_1;
        glove;
        glove_cal_pulls;
        glove_freq;
        glove_ampl;
        ti;
        com_port;
        DataTags;
        bivariate = true;       % If true we vary randomly frequency AND gain/amplitude in the phase 1 sequence; only frequency otherwise
        answers;

        % Phase 2 properties
        n_trial;
        this_trial_path_3;
        c;
        m;
        Z;
        Z_old;
        phi;
        var;
        same = 0;
        f_interval = 5;
        % this is the interval in which frequency must stay to enable
        % the sequence termination
        a_interval = 3;
        % this is the interval in which amplitude/torque must stay to enable
        % the sequence termination
        s_cutoff = 10;
        % this is the number of stimulations in which the VAR must stay to
        % enable the sequence termination
    end
    
    methods (Access = private)

        function vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index)
            devIndex = 0;               % Hard coded until pilot trials
            if (app.ti.deviceConnected == true && vib_index(1) == 1)
                app.ti.ChangeFreq(devIndex, 1, freq, 0);
                app.ti.ChangeGain(devIndex, 1, gain, 0);
                app.ti.Pulse(devIndex,1, pulse_delta, 0);
                app.ti.Pulse(devIndex, 1, pulse_delta, delay-2);
            end
            if (app.ti.deviceConnected == true && vib_index(2) == 1)
                app.ti.ChangeFreq(devIndex, 2, freq, 0);
                app.ti.ChangeGain(devIndex, 2, gain, 0);
                app.ti.Pulse(devIndex, 2, pulse_delta, 1);
                app.ti.Pulse(devIndex, 2, pulse_delta, delay-1);
            end
            if (app.ti.deviceConnected == true && vib_index(3) == 1)
                app.ti.ChangeFreq(devIndex, 3, freq, 0);
                app.ti.ChangeGain(devIndex, 3, gain, 0);
                app.ti.Pulse(devIndex, 3, pulse_delta, 2);
                app.ti.Pulse(devIndex, 3, pulse_delta, delay);
            end
        end

        function adaptive_sequence3(app)
            switch length(app.var)
                case 0
                    if app.TrialVariableDropDown.Value == "Frequency"
                        app.c = 50;
                        % empirically chosen constant
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.phi = 0.8;
                        % confidence of the achieved threshold result
                        app.var = 50;
                        % starting frequency value, empirically chosen
                        app.same = 0;
                        % for redundance
                        app.var = app.var + app.c / (1 + app.m) * (app.Z - app.phi);
                        % formula for ascending threshold
                        app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with Frequency of ", num2str(app.var(end)), " and fixed Torque"); "---";
                            "Please, keep in mind sensed kinesthetic illusion for future comparisons"];
                    elseif app.TrialVariableDropDown.Value == "Ampl_Torque"
                        app.c = 50;
                        % empirically chosen constant TO BE DETERMINED
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.phi = 0.8;
                        % confidence of the achieved threshold result
                        app.var = 40;
                        % starting torque value, empirically chosen TO BE
                        % DETERMINED
                        app.same = 0;
                        % for redundance
                        app.var = app.var - app.c / (1 + app.m) * (app.Z - app.phi); % 40 - 15/1*(1-0.8)
                        % formula for descending threshold
                        app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with Torque of ", num2str(app.var(end)), " and fixed Frequency"); "---";
                            "Please, keep in mind sensed kinesthetic illusion for future comparisons"];
                    end
                    app.StartSequenceButton.Text = "Deliver Second Stimulation";
                    app.MoreIntenseButton.Enable = "off";
                    app.LessIntenseButton.Enable = "off";
                    app.SameIntensityButton.Enable = "off";
                    app.MoreIntenseButton.Visible = "on";
                    app.LessIntenseButton.Visible = "on";
                    app.SameIntensityButton.Visible = "on";
                case 1
                    switch app.TrialVariableDropDown.Value
                        case "Frequency"
                            new_var = app.var(end) + app.c / (1 + app.m) * (app.Z - app.phi);
                        case "Ampl_Torque"
                            new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                    end
                    app.var = [app.var new_var];
                    app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable fixed"); "---";
                        "Please express a comparison between this kinesthetic illusion and the last one";
                        "---";
                        "Was it more intense? Less intense? Or the same intensity?";
                        "---";
                        "Use the buttons on the right to answer"];
                    app.StartSequenceButton.Text = "Sequence Ongoing";
                    app.StartSequenceButton.Enable = "off";
                    app.MoreIntenseButton.Enable = "on";
                    app.LessIntenseButton.Enable = "on";
                    app.SameIntensityButton.Enable = "on";
                    app.MoreIntenseButton.Visible = "on";
                    app.LessIntenseButton.Visible = "on";
                    app.SameIntensityButton.Visible = "on";
                otherwise
                    if app.Z ~= app.Z_old
                        app.m = app.m + 1;
                    end
                    switch app.TrialVariableDropDown.Value
                        case "Frequency"
                            new_var = app.var(end) + app.c / (1 + app.m) * (app.Z - app.phi);
                        case "Ampl_Torque"
                            new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                    end
                    app.var = [app.var new_var];
                    app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable fixed"); "---";
                        "Please express a comparison between this kinesthetic illusion and the last one";
                        "---";
                        "Was it more intense? Less intense? Or the same intensity?";
                        "---";
                        "Use the buttons on the right to answer";
                        "---";
                        "Now the sequence will keep going with each comparison answered!"];
                    if length(app.var) > 15
                        switch app.TrialVariableDropDown.Value
                            case "Frequency"
                                interval = app.f_interval;                                  % These intervals are chosen as intervals of non intellegible difference (meeting)
                            case "Ampl_Torque"
                                interval = app.a_interval;
                        end
                        if max(app.var(end-app.s_cutoff:end)) - min(app.var(end-app.s_cutoff:end)) <= interval
                            app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable fixed"); "---";
                                "Please express a comparison between this kinesthetic illusion and the last one";
                                "---";
                                "Was it more intense? Less intense? Or the same intensity?";
                                "---";
                                "Use the buttons on the right to answer";
                                "---";
                                "Now the sequence will keep going with each comparison answered!";
                                "---";
                                strcat("THE LAST ", num2str(app.s_cutoff), " STIMULATIONS ARE WITHIN THE SEQUENCE TERMINATION INTERVAL OF ", num2str(interval));
                                "YOU CAN EXIT THE SEQUENCE BY SAVING WITH BUTTONS BELOW!"];
                            app.SaveDataButton.Enable = "on";
                            app.SaveImageButton.Enable = "on";
                            app.SavebothDataandImageButton.Enable = "on";
                        end
                    end                               
                    app.Z_old = app.Z;
            end
            draw_sequence3(app)
        end

        function draw_sequence3(app)
            if app.TrialVariableDropDown.Value == "Frequency"
                title(app.UIAxes, 'Frequency Adaptive Sequence');
                app.UIAxes.YLim = [0 max(app.var)+10];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            elseif app.TrialVariableDropDown.Value == "Ampl_Torque"
                title(app.UIAxes, 'Current Ampl / Torque Adaptive Sequence');
                app.UIAxes.YLim = [min(app.var)-5 40];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            end
            app.UIAxes.XLim = [0 length(app.var)+1];
            plot(app.UIAxes,length(app.var),app.var(end),"r","Marker","hexagram","MarkerSize",10)
        end

        function save_phase3(app, data, image)
            if data == true
                % Save only the data gathered
                writematrix(app.var, strcat(app.this_trial_path_3, "\Phase3_Trial_", num2str(app.ThisTrialNEditField.Value), "_", app.TrialVariableDropDown.Value, "_Data.xlsx"));
            end
            if image == true
                % Save the image in png format
                figure;
                % Create new axis
                newAxes = axes;
                % Copy all objects from UIAxes to new axis
                newAxes.Title= app.UIAxes.Title;
                newAxes.XLabel = app.UIAxes.XLabel;
                newAxes.YLabel = app.UIAxes.YLabel;
                grid(newAxes,'on');
                copyobj(app.UIAxes.Children, newAxes)
                % in order to keep titles and labels on uiaxes
                xlabel(app.UIAxes, newAxes.XLabel.String);
                ylabel(app.UIAxes, newAxes.YLabel.String);
                title(app.UIAxes, newAxes.Title.String);
                fig2Save = ancestor(newAxes, 'figure');
                fig2Save.Visible='off';
                saveas(fig2Save, strcat(app.this_trial_path_3, "\Phase3_Trial_", num2str(app.ThisTrialNEditField.Value), "_", app.TrialVariableDropDown.Value, "_Image.png"));
            end
            app.InfoBox3.Value = "File(s) saved. Now more data can be gathered in 3... 2...";
            pause(3);
            % resetting state as new
            app.StartSequenceButton.Enable = "on";
            app.StartSequenceButton.Text = "Start Sequence";
            app.MoreIntenseButton.Enable = 0;
            app.LessIntenseButton.Enable = 0;
            app.SameIntensityButton.Enable = 0;
            app.ThisTrialNEditField.Enable = 1;
            app.TrialVariableDropDown.Enable = 1;
            app.CreateTrialDirectoryButton.Enable = 1;
            app.SaveDataButton.Enable = 0;
            app.SaveImageButton.Enable = 0;
            app.SavebothDataandImageButton.Enable = 0;
            app.var = [];
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes,"off");
            Phase3AdaptiveThresholdsTabButtonDown(app)
        end
 
        function KillTactors(app) %#ok<MANU> 
            try
                clear app.ti
            catch
                disp("No Tactor Interfaces connected at the moment");
            end
        end

        function KillGlove(app)
            try
                fclose(app.glove.serial_p);
                delete(app.glove.serial_p);
                clear app.glove
            catch
                disp("No CyberGloves connected at the moment");
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InfoBox1.Value = "Opening prompt for trial directory selection in 3...";
            pause(4);

            cd(uigetdir);
            
            
            app.today = string(datetime('now','Format','dd-MM-yy'));
            app.path_1 = strcat('Phase1_',app.today);
            app.path_2 = strcat('Phase2_',app.today);
            app.path_3 = strcat('Phase3_',app.today);
            addpath MATLAB64_TDK-1.0.5.0\

            if ~exist(app.path_1, 'dir')
                mkdir(app.path_1);
                addpath(app.path_1);
            end

            if ~exist(app.path_2,"dir")
                mkdir(app.path_2);
                addpath(app.path_2);
            end

            if ~exist(app.path_3,"dir")
                mkdir(app.path_3);
                addpath(app.path_3);
            end
            
            % Compile list of COM ports available at startup
            serials = serialportlist;
            app.PortaListBox.Items = serials;
            app.com_port = app.PortaListBox.Value;

%             if ~exist("DataTags","var")
%                 if exist("DataTags.mat", "file") ~= 0
%                     load DataTags.mat DataTags
%                 end
%             end
            
            % Load column names for cyberglove data
            % app.DataTags = DataTags; %#ok<ADPROP>         
    
            app.EditFieldDur.Value = 2000;
            app.EditFieldFreq.Value = 100;
            app.EditFieldGain.Value = 200;

            app.DuratamsKnob.Value = app.EditFieldDur.Value;
            app.FreqHzKnob.Value = app.EditFieldFreq.Value;
            app.GainKnob.Value = app.EditFieldGain.Value;

            app.InfoBox1.Value = ["In this Phase 1 of the MYKI Proprioception Trial we will deliver vibrations through skin vibrators, called Tactors.";
                "---";
                "Please start by connecting the Tactor Unit to the PC.";
                "Then select the device in the COM Ports list (after Updating the list by clicking on the tab title) and press Initialize Tactor Interface."];

        end

        % Button pushed function: InitializeTactorInterfaceButton
        function InitializeTactorInterfaceButtonPushed(app, event)
            try
                app.ti = TactorInterfaceML;
                app.ti.Discover;
                app.ti.Connect(app.PortaListBox.Value, 1);
                if app.ti.deviceConnected == true
                    pause(2);
                    text = "Connection to Tactor Interface successful. You can freely use the Tactors now from the panel below.";
                    app.TactorsPanel.Enable = 1;
                    app.InitializeTactorInterfaceButton.Enable = 0;
                else
                    text = "An error occurred during the connection, try selecting a different port.";
                end
            catch
                text = "An error occurred, Tactor Interface already present. Close it before retrying.";
            end

            app.InfoBox1.Value = text;
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            try
                freq = round(10 * app.FreqHzKnob.Value);                        % ChangeFreq wants frequency 1/10 Hz
                gain = round(app.GainKnob.Value);
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];
                pulse_delta = round(app.DuratamsKnob.Value) / 2;
                % pulse_delta, its duration, is divided by two because the
                % max the function can take is 2500 ms.
                delay = pulse_delta / 10 - 1;
                % by applying a delay (divided by 10 because the app wants
                % centiseconds) we can double stimulation length.

                vibrateTactors(app,freq,gain,pulse_delta,delay,vib_index)
                text = ["---"; "---"; strcat("Delivered a vibration with Frequency ", num2str(freq/10), " Hz, and Gain ", num2str(gain), ".");
                    "---";
                    "If no vibration was detected try to select different Tactors in the check boxes, or manually move the tactor jacks.";
                    "---";
                    "When the subject has recognized il kinaesthetic illusion use the Questionnaire below to record data."];
            catch
                text = "An error occurred, likely the connection to Tactors is lost. Press the button Close Tactors Interface and reconnect.";
            end

            app.InfoBox1.Value = text;
            scroll(app.InfoBoxInitializationPanel, 'bottom');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete all interfaces before closing
            KillGlove(app)
            KillTactors(app)

            delete(app)            
        end

        % Value changed function: EditFieldFreq
        function EditFieldFreqValueChanged(app, event)
            value = app.EditFieldFreq.Value;
            app.FreqHzKnob.Value = value;
        end

        % Value changed function: EditFieldGain
        function EditFieldGainValueChanged(app, event)
            value = app.EditFieldGain.Value;
            app.GainKnob.Value = value;
        end

        % Value changed function: EditFieldDur
        function EditFieldDurValueChanged(app, event)
            value = app.EditFieldDur.Value;
            app.DuratamsKnob.Value = value;
        end

        % Value changed function: FreqHzKnob
        function FreqHzKnobValueChanged(app, event)
            value = app.FreqHzKnob.Value;
            app.EditFieldFreq.Value = value;
        end

        % Value changed function: GainKnob
        function GainKnobValueChanged(app, event)
            value = app.GainKnob.Value;
            app.EditFieldGain.Value = value;
        end

        % Value changed function: DuratamsKnob
        function DuratamsKnobValueChanged(app, event)
            value = app.DuratamsKnob.Value;
            app.EditFieldDur.Value = value;
        end

        % Callback function
        function Phase1TactorsStartButtonPushed(app, event)
            
            try
                app.NextStimulationButton.Enable = "on";
                app.RepeatStimulationButton.Enable = "on";
                app.Phase1TactorsFinishButton.Enable = "on";

                post_it(app,["The stimulation-measurement sequence will start by pressing Deliver Next Stimulation";
                    "The number of stimulations is not fixed";
                    "Per ogni stimolazione Frequenza e Gain vengono scelti randomicamente,";
                    "mentre Durata e #Tactor vengono controllati dal pannello Tactors in basso.";
                    "Default sequenza bivariata, si può cambiare nel codice ENG NEEDED"]);

                freq = 10 * (70 + 5 * unidrnd(12));
                % Frequency is pulled from a discrete uniform distribution, from 70 to 130Hz, with 5Hz increments
                if app.bivariate
                    gain = 50 + 10 * unidrnd(20,20,1);
                    % Gain is pulled from a discrete uniform
                    % distribution, from 50 to 250, with increments
                    % of 10
                else
                    gain = 200;
                    % Gain is fixed, chosen as "strong but not too strong"
                end
                app.EditFieldFreq.Value = freq;
                app.EditFieldGain.Value = gain;
                pulse_delta = app.EditFieldDur.Value;
                delay = pulse_delta / 10 - 1;
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

                vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);

                t = strcat("Sent vibration with these characteristics:\n Freq = ", freq, "Hz, gain = ", gain, "\n");
                post_it(app,t,true);

                app.Phase1TactorsStartButton.Enable = "off";
            catch
                post_it(app,"Tactors not connected properly, please retry")
            end
        end

        % Callback function
        function RepeatStimulationButtonPushed(app, event)
            try
                % This time we get the vibration values straight from the knobs

                freq = 10 * app.EditFieldFreq.Value;
                gain = app.EditFieldGain.Value;
                pulse_delta = app.EditFieldDur.Value;
                delay = pulse_delta / 10 - 1;
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

                vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);
            catch
                post_it(app, "Something went wrong, and it shouldn't have..")
            end
        end

        % Callback function
        function NextStimulationButtonPushed(app, event)
            % We save the current answers and execute the next random vibration
            
            app.answers = [app.answers; app.EditFieldFreq.Value app.EditFieldGain.Value app.ListBox.Value app.ListBox_2.Value app.ListBox_3.Value app.ListBox_4.Value];

            freq = 10 * (60 + 5 * unidrnd(16));
            % Frequency is pulled from a discrete uniform distribution, from 60 to 140Hz, with 5Hz increments
            if app.bivariate
                gain = 50 + 10 * unidrnd(20,20,1);
                % Gain is pulled from a discrete uniform
                % distribution, from 50 to 250, with increments
                % of 10
            else
                gain = 200;
                % Gain is fixed, chosen as "strong but not too strong"
            end
            app.EditFieldFreq.Value = freq;         
            app.EditFieldGain.Value = gain;
            pulse_delta = app.EditFieldDur.Value;
            delay = pulse_delta / 10 - 1;
            vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

            vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);

            t = strcat("Sent vibration with these characteristics:\n Freq = ", freq, "Hz, gain = ", gain, "\n");
            posti_it(app,t,true);
        end

        % Callback function
        function Phase1TactorsFinishButtonPushed(app, event)
            if isfile(strcat(app.path_1, "\", "Answers_Phase1_Tactor_", app.today, ".xlsx"))
                post_it(app,"Answers file already present, please rename it to save it or delete it.")
            else
                answers_ = array2table(app.answers);
                answers_.Properties.VariableNames(1:6) = ["Freq" "Gain" "Answer 1" "Answer 2" "Answer 3" "Answer 4"];
                % should be ok saving like this
                writetable(answers_,strcat(app.path_1, "\", "Answers_Phase1_Tactor_", app.today, ".xlsx"),"WriteMode","append");
                pause(1);
                app.answers = [];
                app.NextStimulationButton.Enable = "off";
                app.RepeatStimulationButton.Enable = "off";
                app.Phase1TactorsFinishButton.Enable = "off";
                app.Phase1TactorsStartButton.Enable = "on";
            end
        end

        % Callback function
        function Phase1GloveStartButtonPushed(app, event)
            if ~exist(app.path_1, 'dir')
                mkdir(app.path_1);
                addpath(app.path_1);
            end
            
            try
                post_it(app,["La sequenza di stimolazioni-misurazioni inizierà quando verrà premuto il tasto Invia Prossima Stimolazione";
                    "Il numero di stimolazioni non è vincolato, e la sequenza continuerà fino alla pressione del pulsante Phase 1 Glove - Finish";
                    "Per ogni stimolazione Frequenza e Ampiezza vengono scelti randomicamente,";
                    "e vanno impostati manualmente sul secondo PC collegato ai MYKI Drivers";
                    "Default sequenza bivariata, si può cambiare nel codice ENG NEEDED"]);

                app.NextStimulationButton_2.Enable = "on";
                app.RepeatStimulationButton_2.Enable = "on";
                app.Phase1GloveFinishButton.Enable = "on";

                app.glove_freq = 50 + 5 * unidrnd(12);
                if app.bivariate
                    app.glove_ampl = 5 + 5 * unidrnd(7);
                else
                    app.glove_ampl = 25;
                    % Hard coded as middle ground amplitude, review in meetings
                end

                t = strcat("Setup Drivers to ", app.glove_freq, "Hz frequency and ", app.glove_ampl, "10^(-6)Nm torque \n");
                post_it(app, t, true);

                app.Phase1GlovesStartButton.Enable = "off";
            catch
                post_it(app,"Error message not written yet")
            end
        end

        % Callback function
        function NextStimulationButton_2Pushed(app, event)
            % We save the current answers and execute the next random vibration
            
            app.answers = [app.answers; app.glove_freq app.glove_ampl app.ListBox.Value app.ListBox_2.Value app.ListBox_3.Value app.ListBox_4.Value];

            app.glove_freq = 50 + 5 * unidrnd(12);
            if app.bivariate
                app.glove_ampl = 5 + 5 * unidrnd(7);
            else
                app.glove_ampl = 25;
                % Hard coded as middle ground amplitude, review in meetings
            end

            t = strcat("Setup Drivers to ", app.glove_freq, "Hz frequency and ", app.glove_ampl, "10^(-6)Nm torque \n");
            post_it(app, t, true);

        end

        % Callback function
        function RepeatStimulationButton_2Pushed(app, event)
            t = strcat("Setup Drivers to ", app.glove_freq, "Hz frequency and ", app.glove_ampl, "10^(-6)Nm torque again \n");
            post_it(app, t, true);
        end

        % Callback function
        function Phase1GloveFinishButtonPushed(app, event)
            if isfile(strcat(app.path_1, "\", "Answers_Phase1_Glove_", app.today, ".xlsx"))
                post_it(app,"Answers file already present, please rename it to save it or delete it.")
            else
                answers_ = array2table(app.answers);
                answers_.Properties.VariableNames(1:6) = ["Freq" "Torque" "Answer 1" "Answer 2" "Answer 3" "Answer 4"];
                % should be ok saving like this
                writetable(answers_,strcat(app.path_1, "\", "Answers_Phase1_Glove_", app.today, ".xlsx"),"WriteMode","append");
                pause(1);
                app.answers = [];
                app.NextStimulationButton_2.Enable = "off";
                app.RepeatStimulationButton_2.Enable = "off";
                app.Phase1GloveFinishButton.Enable = "off";
                app.Phase1GloveStartButton.Enable = "on";
            end
        end

        % Button pushed function: CreateTrialDirectoryButton
        function CreateTrialDirectoryButtonPushed(app, event)
            if ~isfolder(app.path_3)
                mkdir(app.path_3);
                addpath(app.path_3);
            end
            
            app.this_trial_path_3 = strcat(app.path_3, "\Trial_", num2str(app.ThisTrialNEditField.Value), "_", app.TrialVariableDropDown.Value);
            if ~isfolder(app.this_trial_path_3)
                mkdir(app.this_trial_path_3);
                addpath(app.this_trial_path_3);
            else
                app.InfoBox3.Value = "Currently chosen trial number and variable directory already existing, all files will be overwritten!";
                pause(3);
            end
            
            dirs = dir;
            dirs = {dirs.name};
            dirs_ = dir(app.path_3);
            dirs_ = {dirs_.name};
            
            app.InfoBox3.Value = [dirs {'---'} {'And into today''s folder', char(strcat(app.path_3, ":"))} {'---'} dirs_ {'---'} {'--> If folders are present, the Sequence can be started using button on the right. <--'}];
        end

        % Button pushed function: StartSequenceButton
        function StartSequenceButtonPushed(app, event)
            % State Machine Implementation for this button
            % First state
            if isfolder(char(app.this_trial_path_3))
                if app.StartSequenceButton.Text == "Start Sequence"
                    switch app.TrialVariableDropDown.Value
                        case "Frequency"
                            axis_unit = " Hz";
                            app.MoreIntenseButton.Text = "More Intense";
                            app.LessIntenseButton.Text = "Less Intense";
                            app.SameIntensityButton.Text = "Same Intensity";
                        case "Ampl_Torque"
                            axis_unit = " exp(-6) Nm";
                            app.MoreIntenseButton.Text = "Illusion Sensed";
                            app.LessIntenseButton.Text = "No Illusion Sensed";
                            app.SameIntensityButton.Text = "Not Sure";
                    end
                    ylabel(app.UIAxes, strcat(app.TrialVariableDropDown.Value, axis_unit))
                    app.ThisTrialNEditField.Enable = "off";
                    app.TrialVariableDropDown.Enable = "off";
                    app.CreateTrialDirectoryButton.Enable = "off";
                    app.SaveDataButton.Enable = "off";
                    app.SaveImageButton.Enable = "off";
                    app.SavebothDataandImageButton.Enable = "off";
                    app.InfoBox3.Value = ["Sequence Starts when button Deliver First Stimulation is pressed";
                        "---";
                        strcat("You have chosen to run trial number ", num2str(app.ThisTrialNEditField.Value), " for the variable ", app.TrialVariableDropDown.Value);
                        "---";
                        "Make sure to check it hasn't been run before"];
                    app.StartSequenceButton.Text = "Deliver First Stimulation";

                    % Second State
                elseif app.StartSequenceButton.Text == "Deliver First Stimulation"
                    adaptive_sequence3(app)

                    % Third State
                elseif app.StartSequenceButton.Text == "Deliver Second Stimulation"
                    adaptive_sequence3(app)

                end
            else
                app.InfoBox3.Value = "Data Directory MUST be created before Start Sequence, use the button on the left and try again!";
            end
        end

        % Button pushed function: ShowDirectoriesButton
        function ShowDirectoriesButtonPushed(app, event)
            dirs = dir;
            dirs = {dirs.name};            
            
            if isfolder(app.path_3)
                dirs_ = dir(app.path_3);
                dirs_ = {dirs_.name};
                app.InfoBox3.Value = [dirs {'---'} {'Today''s folder is present. Here''s what''s in it:'} dirs_ {'---'} {'--> what? <--'}];
            else
                app.InfoBox3.Value = [dirs {'---'} {'--> Check above if the folder for today''s questionnare is present. If not, create it. <--'}];
            end
        end

        % Button pushed function: MoreIntenseButton
        function MoreIntenseButtonPushed(app, event)
            app.Z = 1;
            adaptive_sequence3(app)
            app.same = 0;
        end

        % Button pushed function: LessIntenseButton
        function LessIntenseButtonPushed(app, event)
            app.Z = 0;
            adaptive_sequence3(app)
            app.same = 0;
        end

        % Button pushed function: SameIntensityButton
        function SameIntensityButtonPushed(app, event)
            if app.same == 3
                app.Z = 0;
                adaptive_sequence3(app);
                app.same = 0;
            else
                app.Z = 1;
                adaptive_sequence3(app)
                app.same = app.same + 1;
            end
        end

        % Button down function: Phase3AdaptiveThresholdsTab
        function Phase3AdaptiveThresholdsTabButtonDown(app, event)
            % simple graph setup
            grid(app.UIAxes,'on');
            xticks(app.UIAxes, 0:1:length(app.var)+1);
            xlabel(app.UIAxes, "Stimulus Number")
            hold(app.UIAxes,'on');
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            save_phase3(app, true, false)
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            save_phase3(app, false, true)
        end

        % Button pushed function: SavebothDataandImageButton
        function SavebothDataandImageButtonPushed(app, event)
            save_phase3(app, true, true)
        end

        % Button pushed function: CloseTactorInterfaceButton
        function CloseTactorInterfaceButtonPushed(app, event)
            KillTactors(app)
            pause(1);
            app.InitializeTactorInterfaceButton.Enable = 1;
        end

        % Button pushed function: ShowDirectoriesButtonPhase1
        function ShowDirectoriesButtonPhase1Pushed(app, event)
            dirs = dir;
            dirs = {dirs.name};

            if isfolder(app.path_1)
                dirs_ = dir(app.path_1);
                dirs_ = {dirs_.name};
                app.InfoBox1.Value = [dirs {'---'} {'Today''s Phase 1 folder is present. Here''s what''s in it:'} dirs_ {'---'} {'--> what? <--'}];
            else
                app.InfoBox1.Value = [dirs {'---'} {'--> Check above if the folder for today''s questionnare is present. If not, create it. <--'}];
            end
        end

        % Button pushed function: CreateTrialDirectoryPhase1
        function CreateTrialDirectoryPhase1ButtonPushed(app, event)
            if ~isfolder(app.path_1)
                mkdir(app.path_1);
                addpath(app.path_1);
            end
            
            app.this_trial_path_1 = strcat(app.path_1, "\Trial_", num2str(app.TrialEditField.Value));
            if ~isfolder(app.this_trial_path_1)
                mkdir(app.this_trial_path_1);
                addpath(app.this_trial_path_1);
            else
                addpath(app.this_trial_path_1);
                app.InfoBox1.Value = "Currently chosen trial number and variable directory already existing, all files will be overwritten!";
                pause(3);
            end
            
            dirs = dir(app.path_1);
            dirs = {dirs.name};

            if isfolder(app.path_1)
                dirs_ = dir(app.this_trial_path_1);
                dirs_ = {dirs_.name};
                app.InfoBox1.Value = [dirs {'---'} {'Trial''s folder created. Here''s what''s in it:'} dirs_ {'---'} {'--> Now you can proceed with the questionnarie with some random stimulations. <--'}];
            else
                app.InfoBox1.Value = [dirs {'---'} {'--> Check above if the folder for today''s questionnare is present. If not, create it. <--'}];
            end
        end

        % Button down function: COMPortsUpdatePanel
        function COMPortsUpdatePanelButtonDown(app, event)
            serials = serialportlist;
            app.PortaListBox.Items = serials;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'F1MYKI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Phase1TrainingandCalibrationTab = uitab(app.TabGroup);
            app.Phase1TrainingandCalibrationTab.Title = 'Phase 1 - Training and Calibration';
            app.GrigliaMAIN = uigridlayout(app.Phase1TrainingandCalibrationTab);
            app.ProprioceptiveIllusionSurveyPanel = uipanel(app.GrigliaMAIN);
            app.ProprioceptiveIllusionSurveyPanel.Title = 'Proprioceptive Illusion Survey';
            app.GridLayout3 = uigridlayout(app.ProprioceptiveIllusionSurveyPanel);
            app.StartSurveyButton = uibutton(app.GridLayout3, 'push');
            app.StartSurveyButton.Text = 'Start Survey';
            app.SaveSurveyAnswersButton = uibutton(app.GridLayout3, 'push');
            app.SaveSurveyAnswersButton.Text = {'Save Survey'; 'Answers'};
            app.NextStimulationButton = uibutton(app.GridLayout3, 'push');
            app.NextStimulationButton.Text = 'Next Stimulation';
            app.RepeatStimulationButton = uibutton(app.GridLayout3, 'push');
            app.RepeatStimulationButton.Text = 'Repeat Stimulation';
            app.DidyoufeelatactilevibrationLabel = uilabel(app.GridLayout3);
            app.DidyoufeelatactilevibrationLabel.Text = 'Did you feel a tactile vibration?';
            app.DidyoufeelasensationofmovementLabel = uilabel(app.GridLayout3);
            app.DidyoufeelasensationofmovementLabel.Text = 'Did you feel a sensation of movement?';
            app.WhatjointdidyoufeelmoveLabel = uilabel(app.GridLayout3);
            app.WhatjointdidyoufeelmoveLabel.Text = 'What joint did you feel move?';
            app.InwhichdirectionwasthatjoingmovingLabel = uilabel(app.GridLayout3);
            app.InwhichdirectionwasthatjoingmovingLabel.Text = 'In which direction was that joing moving?';
            app.DefinethestrengthvividnessofthemovementsensationLabel = uilabel(app.GridLayout3);
            app.DefinethestrengthvividnessofthemovementsensationLabel.Text = 'Define the strength, vividness of the movement sensation';
            app.DefinethedistancethismovementspannedontoLabel = uilabel(app.GridLayout3);
            app.DefinethedistancethismovementspannedontoLabel.Text = 'Define the distance this movement spanned onto';
            app.DefinethespeedofthissensedmovementLabel = uilabel(app.GridLayout3);
            app.DefinethespeedofthissensedmovementLabel.Text = 'Define the speed of this sensed movement';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'Yes', 'No'};
            app.DropDown.Placeholder = 'Choose Here';
            app.DropDown.Value = 'Yes';
            app.DropDown_2 = uidropdown(app.GridLayout3);
            app.DropDown_2.Items = {'Yes', 'No'};
            app.DropDown_2.Placeholder = 'Choose Here';
            app.DropDown_2.Value = 'Yes';
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.Placeholder = 'Write Here';
            app.DropDown_3 = uidropdown(app.GridLayout3);
            app.DropDown_3.Items = {'Flexion', 'Extension'};
            app.DropDown_3.Placeholder = 'Choose Here';
            app.DropDown_3.Value = 'Flexion';
            app.DropDown_4 = uidropdown(app.GridLayout3);
            app.DropDown_4.Items = {'No Sensation', 'Almost inperceptible', 'Slightly perceptible', 'Midly perceptible', 'Distinctly perceptible', 'Vivid'};
            app.DropDown_4.Placeholder = 'Choose Here';
            app.DropDown_4.Value = 'No Sensation';
            app.DropDown_5 = uidropdown(app.GridLayout3);
            app.DropDown_5.Items = {'No Movement', 'Almost No Movement', 'A Few mm', 'A Few cm', 'More than 5 cm'};
            app.DropDown_5.Placeholder = 'Choose Here';
            app.DropDown_5.Value = 'No Movement';
            app.DropDown_6 = uidropdown(app.GridLayout3);
            app.DropDown_6.Items = {'No Movement', 'Basically Sill', 'Very Slow', 'Perceptible Movement', 'Fast Movement', 'Very Fast'};
            app.DropDown_6.Placeholder = 'Choose Here';
            app.DropDown_6.Value = 'No Movement';
            app.TactorsPanel = uipanel(app.GrigliaMAIN);
            app.TactorsPanel.Enable = 'off';
            app.TactorsPanel.Title = 'Tactors';
            app.GridLayout2 = uigridlayout(app.TactorsPanel);
            app.EditFieldFreq = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldFreq.Limits = [60 160];
            app.EditFieldFreq.ValueDisplayFormat = '%.0f';
            app.EditFieldFreq.ValueChangedFcn = createCallbackFcn(app, @EditFieldFreqValueChanged, true);
            app.EditFieldFreq.Value = 60;
            app.GainKnob = uiknob(app.GridLayout2, 'continuous');
            app.GainKnob.Limits = [50 255];
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainKnobValueChanged, true);
            app.GainKnob.Value = 50;
            app.EditFieldGain = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldGain.Limits = [50 255];
            app.EditFieldGain.ValueDisplayFormat = '%.0f';
            app.EditFieldGain.ValueChangedFcn = createCallbackFcn(app, @EditFieldGainValueChanged, true);
            app.EditFieldGain.Value = 50;
            app.EditFieldDur = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldDur.Limits = [100 5000];
            app.EditFieldDur.ValueDisplayFormat = '%.0f';
            app.EditFieldDur.ValueChangedFcn = createCallbackFcn(app, @EditFieldDurValueChanged, true);
            app.EditFieldDur.Value = 100;
            app.DuratamsKnob = uiknob(app.GridLayout2, 'continuous');
            app.DuratamsKnob.Limits = [100 5000];
            app.DuratamsKnob.ValueChangedFcn = createCallbackFcn(app, @DuratamsKnobValueChanged, true);
            app.DuratamsKnob.Value = 100;
            app.FIREButton = uibutton(app.GridLayout2, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Text = 'FIRE';
            app.TactorsONPanel = uipanel(app.GridLayout2);
            app.TactorsONPanel.Title = 'Tactors ON';
            app.GridLayout6 = uigridlayout(app.TactorsONPanel);
            app.Tactor1CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor1CheckBox.Text = 'Tactor 1';
            app.Tactor1CheckBox.Value = true;
            app.Tactor2CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor2CheckBox.Text = 'Tactor 2';
            app.Tactor3CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor3CheckBox.Text = 'Tactor 3';
            app.FreqHzKnob = uiknob(app.GridLayout2, 'continuous');
            app.FreqHzKnob.Limits = [60 160];
            app.FreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqHzKnobValueChanged, true);
            app.FreqHzKnob.Value = 60;
            app.FreqHzLabel = uilabel(app.GridLayout2);
            app.FreqHzLabel.Text = 'Freq (Hz)';
            app.GainLabel = uilabel(app.GridLayout2);
            app.GainLabel.Text = 'Gain';
            app.TimemsLabel = uilabel(app.GridLayout2);
            app.TimemsLabel.Text = 'Time (ms)';
            app.InfoBoxInitializationPanel = uipanel(app.GrigliaMAIN);
            app.InfoBoxInitializationPanel.Title = 'Info Box & Initialization';
            app.GridLayout16 = uigridlayout(app.InfoBoxInitializationPanel);
            app.InfoBox1 = uitextarea(app.GridLayout16);
            app.COMPortsUpdatePanel = uipanel(app.GridLayout16);
            app.COMPortsUpdatePanel.Title = 'COM Ports - Update';
            app.COMPortsUpdatePanel.ButtonDownFcn = createCallbackFcn(app, @COMPortsUpdatePanelButtonDown, true);
            app.GridLayout8 = uigridlayout(app.COMPortsUpdatePanel);
            app.PortaListBox = uilistbox(app.GridLayout8);
            app.PortaListBox.Items = {};
            app.PortaListBox.Value = {};
            app.InitializeTactorInterfaceButton = uibutton(app.GridLayout16, 'push');
            app.InitializeTactorInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeTactorInterfaceButtonPushed, true);
            app.InitializeTactorInterfaceButton.Text = {'Initialize'; 'Tactor Interface'};
            app.CloseTactorInterfaceButton = uibutton(app.GridLayout16, 'push');
            app.CloseTactorInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @CloseTactorInterfaceButtonPushed, true);
            app.CloseTactorInterfaceButton.Text = {'Close'; 'Tactor Interface'};
            app.ShowDirectoriesButtonPhase1 = uibutton(app.GridLayout16, 'push');
            app.ShowDirectoriesButtonPhase1.ButtonPushedFcn = createCallbackFcn(app, @ShowDirectoriesButtonPhase1Pushed, true);
            app.ShowDirectoriesButtonPhase1.Text = 'Show Directories';
            app.CreateTrialDirectoryPhase1 = uibutton(app.GridLayout16, 'push');
            app.CreateTrialDirectoryPhase1.ButtonPushedFcn = createCallbackFcn(app, @CreateTrialDirectoryPhase1ButtonPushed, true);
            app.CreateTrialDirectoryPhase1.Text = 'Create Trial Directory';
            app.TrialEditFieldLabel = uilabel(app.GridLayout16);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialEditField = uieditfield(app.GridLayout16, 'numeric');
            app.Phase2GloveTrackingTab = uitab(app.TabGroup);
            app.Phase2GloveTrackingTab.Title = 'Phase 2 - Glove Tracking';
            app.Phase3AdaptiveThresholdsTab = uitab(app.TabGroup);
            app.Phase3AdaptiveThresholdsTab.Title = 'Phase 3 - Adaptive Thresholds';
            app.Phase3AdaptiveThresholdsTab.ButtonDownFcn = createCallbackFcn(app, @Phase3AdaptiveThresholdsTabButtonDown, true);
            app.GridLayout11 = uigridlayout(app.Phase3AdaptiveThresholdsTab);
            app.SequenceInitializationandExecutionPanel = uipanel(app.GridLayout11);
            app.SequenceInitializationandExecutionPanel.Title = 'Sequence Initialization and Execution';
            app.GridLayout12 = uigridlayout(app.SequenceInitializationandExecutionPanel);
            app.ThisTrialNEditFieldLabel = uilabel(app.GridLayout12);
            app.ThisTrialNEditFieldLabel.Text = 'This Trial N°';
            app.ThisTrialNEditField = uieditfield(app.GridLayout12, 'numeric');
            app.ThisTrialNEditField.Value = 1;
            app.TrialVariableDropDownLabel = uilabel(app.GridLayout12);
            app.TrialVariableDropDownLabel.Text = 'Trial Variable';
            app.TrialVariableDropDown = uidropdown(app.GridLayout12);
            app.TrialVariableDropDown.Items = {'Frequency', 'Ampl_Torque'};
            app.TrialVariableDropDown.Value = 'Frequency';
            app.InfoBox3 = uitextarea(app.GridLayout12);
            app.InfoBox3.Editable = 'off';
            app.InfoBox3.Placeholder = 'Choose progressive number and variable for this trial showing the directories already created, with Show Directories';
            app.CreateTrialDirectoryButton = uibutton(app.GridLayout12, 'push');
            app.CreateTrialDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTrialDirectoryButtonPushed, true);
            app.CreateTrialDirectoryButton.Text = 'Create Trial Directory';
            app.StartSequenceButton = uibutton(app.GridLayout12, 'push');
            app.StartSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @StartSequenceButtonPushed, true);
            app.StartSequenceButton.Text = 'Start Sequence';
            app.LessIntenseButton = uibutton(app.GridLayout12, 'push');
            app.LessIntenseButton.ButtonPushedFcn = createCallbackFcn(app, @LessIntenseButtonPushed, true);
            app.LessIntenseButton.Enable = 'off';
            app.LessIntenseButton.Text = 'Less Intense';
            app.SameIntensityButton = uibutton(app.GridLayout12, 'push');
            app.SameIntensityButton.ButtonPushedFcn = createCallbackFcn(app, @SameIntensityButtonPushed, true);
            app.SameIntensityButton.Enable = 'off';
            app.SameIntensityButton.Text = 'Same Intensity';
            app.ShowDirectoriesButton = uibutton(app.GridLayout12, 'push');
            app.ShowDirectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDirectoriesButtonPushed, true);
            app.ShowDirectoriesButton.Text = 'Show Directories';
            app.MoreIntenseButton = uibutton(app.GridLayout12, 'push');
            app.MoreIntenseButton.ButtonPushedFcn = createCallbackFcn(app, @MoreIntenseButtonPushed, true);
            app.MoreIntenseButton.Enable = 'off';
            app.MoreIntenseButton.Text = 'More Intense';
            app.OutputGraphsPanel = uipanel(app.GridLayout11);
            app.OutputGraphsPanel.Title = 'Output Graphs';
            app.GridLayout14 = uigridlayout(app.OutputGraphsPanel);
            app.UIAxes = uiaxes(app.GridLayout14);
            title(app.UIAxes, 'Adaptive Sequence')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveDataPanel = uipanel(app.GridLayout11);
            app.SaveDataPanel.Title = 'Save Data';
            app.GridLayout15 = uigridlayout(app.SaveDataPanel);
            app.SaveDataButton = uibutton(app.GridLayout15, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.SaveImageButton = uibutton(app.GridLayout15, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.SavebothDataandImageButton = uibutton(app.GridLayout15, 'push');
            app.SavebothDataandImageButton.ButtonPushedFcn = createCallbackFcn(app, @SavebothDataandImageButtonPushed, true);
            app.SavebothDataandImageButton.Enable = 'off';
            app.SavebothDataandImageButton.Text = 'Save both Data and Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MYKI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end